classdef vMRE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MRELoadNIFTISwitch            matlab.ui.control.Switch
        MRELoadNIFTISwitchLabel       matlab.ui.control.Label
        MRELoadDicomSwitch            matlab.ui.control.Switch
        MRELoadDicomSwitchLabel       matlab.ui.control.Label
        LoadDWINIIButton              matlab.ui.control.Button
        VirtualMRELabel               matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ForLiverTab                   matlab.ui.container.Tab
        sADC_b_High_ind               matlab.ui.control.TextArea
        DWIbValueLabel_20             matlab.ui.control.Label
        sADC_b_Low_ind                matlab.ui.control.TextArea
        DWIbValueLabel_19             matlab.ui.control.Label
        SaveResultButton              matlab.ui.control.Button
        CheckSTIFFButton              matlab.ui.control.Button
        RunButton_forLover            matlab.ui.control.Button
        Fit_para_b                    matlab.ui.control.TextArea
        DWIbValueLabel_9              matlab.ui.control.Label
        Fit_para_a                    matlab.ui.control.TextArea
        DWIbValueLabel_8              matlab.ui.control.Label
        b_High_ind                    matlab.ui.control.TextArea
        DWIbValueLabel_7              matlab.ui.control.Label
        b_Low_ind                     matlab.ui.control.TextArea
        DWIbValueLabel_6              matlab.ui.control.Label
        Num_bvalues                   matlab.ui.control.TextArea
        DWIbValueLabel_5              matlab.ui.control.Label
        Check_Img_Slic_B_ind          matlab.ui.control.TextArea
        DWIbValueLabel_4              matlab.ui.control.Label
        Check_Img_B_Slic_ind          matlab.ui.control.TextArea
        DWIbValueLabel_3              matlab.ui.control.Label
        Num_Slices                    matlab.ui.control.TextArea
        DWIbValueLabel_2              matlab.ui.control.Label
        bvals_DWI                     matlab.ui.control.TextArea
        DWIbValueLabel                matlab.ui.control.Label
        ForOtherTissuesTab            matlab.ui.container.Tab
        FileLocationTextArea_ROI      matlab.ui.control.TextArea
        FileLocationTextAreaLabel_2   matlab.ui.control.Label
        LoadROIButton                 matlab.ui.control.Button
        RegistrationSwitch            matlab.ui.control.Switch
        RegistrationSwitchLabel       matlab.ui.control.Label
        Fit_para_b_for_other_Tissue   matlab.ui.control.TextArea
        DWIbValueLabel_18             matlab.ui.control.Label
        Fit_para_a_for_other_Tissue   matlab.ui.control.TextArea
        DWIbValueLabel_17             matlab.ui.control.Label
        b_High_ind_for_other_Tissue   matlab.ui.control.TextArea
        DWIbValueLabel_16             matlab.ui.control.Label
        b_Low_ind_for_other_Tissue    matlab.ui.control.TextArea
        DWIbValueLabel_15             matlab.ui.control.Label
        SaveResultButton_for_other_Tissue  matlab.ui.control.Button
        CheckSTIFFButton_for_other_Tissue  matlab.ui.control.Button
        RunButton_forLover_for_other_Tissue  matlab.ui.control.Button
        CheckMREButton                matlab.ui.control.Button
        CheckROIButton                matlab.ui.control.Button
        DrawROIButton                 matlab.ui.control.Button
        Num_bvalues_for_other_Tissue  matlab.ui.control.TextArea
        DWIbValueLabel_14             matlab.ui.control.Label
        Check_Img_Slic_B_ind_for_other_Tissue  matlab.ui.control.TextArea
        DWIbValueLabel_13             matlab.ui.control.Label
        Check_Img_B_Slic_ind_for_other_Tissue  matlab.ui.control.TextArea
        DWIbValueLabel_12             matlab.ui.control.Label
        Num_Slices_for_other_Tissue   matlab.ui.control.TextArea
        DWIbValueLabel_11             matlab.ui.control.Label
        bvals_DWI_for_other_Tissue    matlab.ui.control.TextArea
        DWIbValueLabel_10             matlab.ui.control.Label
        ROItoROICalibTab              matlab.ui.container.Tab
        State_box_label               matlab.ui.control.TextArea
        StateLabel                    matlab.ui.control.Label
        LoadCalibButton               matlab.ui.control.Button
        FileLocationTextArea_Calib_Excel  matlab.ui.control.TextArea
        FileLocationTextAreaLabel_3   matlab.ui.control.Label
        FileLocationTextArea_MRE      matlab.ui.control.TextArea
        FileLocationTextArea_2Label   matlab.ui.control.Label
        LoadMREButton                 matlab.ui.control.Button
        LoadDWIButton                 matlab.ui.control.Button
        FileLocationTextArea_DWI      matlab.ui.control.TextArea
        FileLocationTextAreaLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDWIButton
        function LoadDWIButtonPushed(app, event)
            global info;
            global data;
            
            info.dwi.dcm_file_location = uigetdir;
            app.FileLocationTextArea_DWI.Value = info.dwi.dcm_file_location;
            info.dwi.nii_file_location = info.dwi.dcm_file_location;
            [info.dwi.bvals, info.dwi.bvecs, info.dwi.Manufaturer,info.dwi.file_name] = dicm2nii(info.dwi.dcm_file_location, info.dwi.nii_file_location,'nii');
            if(length(info.dwi.file_name)>1)
                bvals_tmp = [];
                dwi_img = [];
                for ind_bvals = 1:length(info.dwi.bvals)
                    bvals_tmp = [bvals_tmp;info.dwi.bvals{ind_bvals}];
                    dwi_raw{ind_bvals} = load_untouch_nii([info.dwi.nii_file_location,'\',info.dwi.file_name{ind_bvals},'.nii']);
%                         dwi_img = cat(4,dwi_img,double(fliplr(rot90(dwi_raw.img))));
                end
                [info.dwi.bvals, sort_ind] = sort(bvals_tmp);
            else
                info.dwi.file_name = cell2mat(info.dwi.file_name);
                info.dwi.bvals = cell2mat(info.dwi.bvals);
                dwi_raw = load_untouch_nii([info.dwi.nii_file_location,'\',info.dwi.file_name,'.nii']);
                data.dwi_raw = double(fliplr(rot90(dwi_raw.img)));
            end
            if ~isempty(info.dwi)
                fprintf("DWI data from DICOM convert to NIFTI Successful!!!\n");
                fprintf("DWI DICOM file location: %s\n",info.dwi.dcm_file_location);
                fprintf("DWI NIFTI file location: %s\n",info.dwi.nii_file_location);
                fprintf("DWI NIFTI file name: %s\n",info.dwi.file_name);
            end
            info.dwi.nii_info = dwi_raw.hdr;
            info.dwi.nii = dwi_raw;
            app.bvals_DWI.Value = mat2str(info.dwi.bvals);
            app.bvals_DWI_for_other_Tissue.Value = mat2str(info.dwi.bvals);
            app.Num_Slices.Value = num2str(size(data.dwi_raw,3));
            app.Num_Slices_for_other_Tissue.Value = num2str(size(data.dwi_raw,3));
            app.Num_bvalues.Value = num2str(size(data.dwi_raw,4));
            app.Num_bvalues_for_other_Tissue.Value = num2str(size(data.dwi_raw,4));
        end

        % Button pushed function: LoadDWINIIButton
        function LoadDWINIIButtonPushed(app, event)
            global info
            global data
            
            [info.dwi.filename, info.dwi.nii_file_location] = uigetfile('*.nii');
            dwi_raw = load_untouch_nii(fullfile(info.dwi.nii_file_location,info.dwi.filename));
            data.dwi_raw = squeeze(double(fliplr(rot90(dwi_raw.img))));
            info.dwi.nii_info = dwi_raw.hdr;
            info.dwi.nii = dwi_raw;
            [info.dwi.bvalfilename, info.dwi.bval_file_location] = uigetfile('*.bval');
            bvals_tmp = importdata(fullfile(info.dwi.bval_file_location,info.dwi.bvalfilename));
            [info.dwi.bvals, sort_ind] = sort(bvals_tmp);
            data.dwi_raw(:,:,:,sort_ind) = data.dwi_raw;
            info.dwi.file_name = info.dwi.filename;
            app.bvals_DWI.Value = mat2str(info.dwi.bvals);
            app.bvals_DWI_for_other_Tissue.Value = mat2str(info.dwi.bvals);
            app.Num_Slices.Value = num2str(size(data.dwi_raw,3));
            app.Num_Slices_for_other_Tissue.Value = num2str(size(data.dwi_raw,3));
            app.Num_bvalues.Value = num2str(size(data.dwi_raw,4));
            app.Num_bvalues_for_other_Tissue.Value = num2str(size(data.dwi_raw,4));
        end

        % Value changed function: Check_Img_B_Slic_ind
        function Check_Img_B_Slic_indValueChanged(app, event)
            global data
            value = app.Check_Img_B_Slic_ind.Value;
            ind_s = str2double(value);
            imlook3d(squeeze(data.dwi_raw(:,:,ind_s,:)));
        end

        % Value changed function: Check_Img_Slic_B_ind
        function Check_Img_Slic_B_indValueChanged(app, event)
            global data
            value = app.Check_Img_Slic_B_ind.Value;
            ind_b = str2double(value);
            imlook3d(squeeze(data.dwi_raw(:,:,:,ind_b)));
        end

        % Button pushed function: RunButton_forLover
        function RunButton_forLoverPushed(app, event)
            global data 
            global info
            app.RunButton_forLover.Enable = 'off';
            b_low_ind = find(info.dwi.bvals==str2double(app.b_Low_ind.Value));
            b_high_ind = find(info.dwi.bvals==str2double(app.b_High_ind.Value));
            a = str2double(app.Fit_para_a.Value);
            b = str2double(app.Fit_para_b.Value);
            fprintf("The Index of the low b is: %d, bval is %d\n",b_low_ind,info.dwi.bvals(b_low_ind));
            fprintf("The Index of the high b is: %d, bval is %d\n",b_high_ind,info.dwi.bvals(b_high_ind));
            fprintf("The Fit Parameter a is: %0.2f\n",a);
            fprintf("The Fit Parameter b is: %0.2f\n",b);
            im_low = squeeze(data.dwi_raw(:,:,:,b_low_ind));
            im_high = squeeze(data.dwi_raw(:,:,:,b_high_ind));
            fprintf("Starting Fit!!!\n");
            data.result.stiff_map = a.*log(im_low./im_high)+ b;
            app.RunButton_forLover.Enable = 'on';
            fprintf("Fit Successful!!!\n");
            sADC_b_low_ind = find(info.dwi.bvals==str2double(app.sADC_b_Low_ind.Value));
            sADC_b_high_ind = find(info.dwi.bvals==str2double(app.sADC_b_High_ind.Value));
            fprintf("The Index of the sADC low b is: %d, bval is %d\n",sADC_b_low_ind,info.dwi.bvals(sADC_b_low_ind));
            fprintf("The Index of the sADC high b is: %d, bval is %d\n",sADC_b_high_ind,info.dwi.bvals(sADC_b_high_ind));
            sADC_im_low = squeeze(data.dwi_raw(:,:,:,sADC_b_low_ind));
            sADC_im_high = squeeze(data.dwi_raw(:,:,:,sADC_b_high_ind));
            data.result.sADC = (log(sADC_im_low ./ sADC_im_high))./(info.dwi.bvals(sADC_b_high_ind)-info.dwi.bvals(sADC_b_low_ind));
        end

        % Button pushed function: CheckSTIFFButton
        function CheckSTIFFButtonPushed(app, event)
            global data 
            imlook3d(data.result.stiff_map);
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            global data 
            global info
            info.result.stiff_filename = [info.dwi.nii_file_location,'\',info.dwi.file_name,'_stiff_map.nii'];
            nii = info.dwi.nii;
            nii.img = flipud(rot90(data.result.stiff_map,-1));
            nii.hdr.dime.dim(1) = 3;
            nii.hdr.dime.dim(5) = 1;
            nii.hdr.dime.datatype = 16;
            nii.hdr.dime.bitpix = 16;
            nii.fileprefix = [nii.fileprefix,'_stiff'];
            fprintf("Saving STIFF Results\n");
            fprintf("STIFF File Location & name: %s\n",info.result.stiff_filename);
%             nii = make_nii(rot90(data.result.stiff_map,-1), info.dwi.nii_info.dime.pixdim(2:4), [], 16);
            save_untouch_nii(nii, info.result.stiff_filename);
            fprintf("Save STIFF Results Successful!!!\n");

            info.result.sADC_filename = [info.dwi.nii_file_location,'\',info.dwi.file_name,'_sADC_map.nii'];
            nii.img = flipud(rot90(data.result.sADC,-1));
            nii.fileprefix = [nii.fileprefix,'_sADC'];
            fprintf("Saving sADC Results\n");
            fprintf("sADC File Location & name: %s\n",info.result.sADC_filename);
            save_untouch_nii(nii, info.result.sADC_filename);
            fprintf("Save sADC Results Successful!!!\n");
        end

        % Value changed function: Check_Img_B_Slic_ind_for_other_Tissue
        function Check_Img_B_Slic_ind_for_other_TissueValueChanged(app, event)
            global data
            value = app.Check_Img_B_Slic_ind_for_other_Tissue.Value;
            ind_s = str2double(value);
            imlook3d(squeeze(data.dwi_raw(:,:,ind_s,:)));
        end

        % Value changed function: Check_Img_Slic_B_ind_for_other_Tissue
        function Check_Img_Slic_B_ind_for_other_TissueValueChanged(app, event)
            global data
            value = app.Check_Img_Slic_B_ind_for_other_Tissue.Value;
            ind_b = str2double(value);
            imlook3d(squeeze(data.dwi_raw(:,:,:,ind_b)));
        end

        % Button pushed function: LoadMREButton
        function LoadMREButtonPushed(app, event)
            global info;
            global data;
            if (strcmp(app.MRELoadDicomSwitch.Value,'On'))
                [info.mre.dcm_filename, info.mre.dcm_file_location] = uigetfile('*.dcm');
                dcm_info = dicominfo(fullfile(info.mre.dcm_file_location,info.mre.dcm_filename));
                app.FileLocationTextArea_MRE.Value = info.mre.dcm_file_location;
                info.mre.nii_file_location = info.mre.dcm_file_location;
                data.mre_raw = fliplr(squeeze(dicomread(dcm_info)));
            elseif (strcmp(app.MRELoadNIFTISwitch.Value,'On'))
                [info.mre.nii_filename, info.mre.nii_file_location] = uigetfile('*.nii');
                app.FileLocationTextArea_MRE.Value = info.mre.nii_file_location;
                info.mre.nii_file_location = info.mre.nii_file_location;
                mre_raw = load_untouch_nii(fullfile(info.mre.nii_file_location,info.mre.nii_filename));
                data.mre_raw = double(fliplr(rot90(mre_raw.img)));
                info.mre.nii_info = mre_raw.hdr;
            else
                info.mre.dcm_file_location = uigetdir;
                app.FileLocationTextArea_MRE.Value = info.mre.dcm_file_location;
                info.mre.nii_file_location = info.mre.dcm_file_location;
                [info.mre.bvals, info.mre.bvecs, info.mre.Manufaturer,info.mre.file_name] = dicm2nii(info.mre.dcm_file_location, info.mre.nii_file_location,'nii');
                mre_raw = load_untouch_nii([info.mre.nii_file_location,'\',info.mre.file_name{1},'.nii']);
                data.mre_raw = double(fliplr(rot90(mre_raw.img)));
                info.mre.nii_info = mre_raw.hdr;
            end
            tmp = data.dwi_raw(:,:,:,1);
%             if (size(tmp)~=size(data.mre_raw))
%                 data.mre_raw = imresize3(data.mre_raw,size(tmp));
%                 fprintf("Resize MRE data!!!\n");
%             end
            if ~isempty(info.mre)
                fprintf("MRE data from DICOM convert to NIFTI Successful!!!\n");
                fprintf("MRE DICOM file location: %s\n",info.mre.dcm_file_location);
                fprintf("MRE NIFTI file location: %s\n",info.mre.nii_file_location);
                fprintf("MRE NIFTI file name: %s\n",cell2mat(info.mre.file_name));
            end
            
            if (strcmp(app.RegistrationSwitch.Value,'On'))
                fprintf("Starting Registration!!! \n");
                [optimizer,metric] = imregconfig("multimodal");
                moving = data.mre_raw;
                fprintf("Moving is MRE data.\n");
                fixed = data.dwi_raw(:,:,:,1);
                if (size(data.mre_raw,3)<16)
                    moving(:,:,size(data.mre_raw,3)+1:16) = 0;
                    fixed(:,:,size(data.mre_raw,3)+1:16) = 0;
                end
                fprintf("Number of Slice is %d < 16, thus do zero padding to 16 slices.\n",size(data.mre_raw,3));
                data.mre_reg = imregister(moving,fixed,'affine',optimizer,metric);
                if (size(data.mre_raw,3)<16)
                    data.mre_reg(:,:,size(data.mre_raw,3)+1:16) = [];
                end
                fprintf("Registeration Successful!!!\n")
                data.mre_raw1 = data.mre_raw;
                data.mre_raw = data.mre_reg;
            end
        end

        % Button pushed function: CheckMREButton
        function CheckMREButtonPushed(app, event)
            global data 
            imlook3d(data.mre_raw);
        end

        % Button pushed function: DrawROIButton
        function DrawROIButtonPushed(app, event)
            global data
            DrawData = data.dwi_raw(:,:,:,1);
            mask = zeros(size(DrawData));
            DrawData(isnan(DrawData)) = 0;
            DrawData(isinf(DrawData)) = 0;
            for ind = 1:size(DrawData,3)
                tmp = DrawData(:,:,ind);
                if (any(tmp(:)))
                    figure, imagesc(DrawData(:,:,ind),[min(min(DrawData(:,:,ind))), max(max(DrawData(:,:,ind)))]);
                    set(gcf,'position',[5, 5,size(mask,2)*5 ,size(mask,1)*5])
                    colormap("gray");
                    ROI = roipoly;
                    if ~isempty(ROI)
                        mask(:,:,ind) = ROI;
                        close;
                    else
                        close;
                    end
                end
            end
            data.mre_roi = mask;
        end

        % Button pushed function: CheckROIButton
        function CheckROIButtonPushed(app, event)
            global data 
            imlook3d(data.mre_roi);
        end

        % Button pushed function: RunButton_forLover_for_other_Tissue
        function RunButton_forLover_for_other_TissuePushed(app, event)
            global data 
            global info
            fit_ind = find(data.mre_roi>0);
            fit_mre = data.mre_raw(fit_ind);
            num_bvals = size(data.dwi_raw,4);
            fprintf("Starting Fit a, b, low bval & high bval!!!\n");
            fit_counter = 0;
            for ind_low_b = 1:num_bvals-1
                for ind_high_b = ind_low_b+1:num_bvals
                    fit_counter = fit_counter + 1;
                    bval_low(fit_counter) = info.dwi.bvals(ind_low_b);
                    bval_high(fit_counter) = info.dwi.bvals(ind_high_b);
                    im_low = squeeze(data.dwi_raw(:,:,:,ind_low_b)).*data.mre_roi;
                    im_high = squeeze(data.dwi_raw(:,:,:,ind_high_b)).*data.mre_roi;
                    sADC = log(im_low./im_high)./(bval_high(fit_counter)-bval_low(fit_counter));
                    sADC(isnan(sADC)) = 0;
                    sADC(isinf(sADC)) = 0;
                    fit_sADC = sADC(fit_ind);
                    [fitresult, gof] = linearfit_vMRE_other_Tissues(fit_sADC, fit_mre);
                    fit_a(fit_counter) = fitresult.a;
                    fit_b(fit_counter) = fitresult.b;
                    fit_gof(fit_counter) = gof.rsquare;
                end
            end
            %% find the max gof
            best_ind = find(fit_gof == max(fit_gof));
            info.fitresult.b_low = bval_low(best_ind(1));
            info.fitresult.b_high = bval_high(best_ind(1));
            info.fitresult.a = fit_a(best_ind(1));
            info.fitresult.b = fit_b(best_ind(1));
            app.b_Low_ind_for_other_Tissue.Value = num2str(info.fitresult.b_low);
            app.b_High_ind_for_other_Tissue.Value = num2str(info.fitresult.b_high);
            app.Fit_para_a_for_other_Tissue.Value = num2str(info.fitresult.a);
            app.Fit_para_b_for_other_Tissue.Value = num2str(info.fitresult.b);

            %% start Fitting
            app.RunButton_forLover_for_other_Tissue.Enable = 'off';
            b_low_ind = find(info.dwi.bvals==str2double(app.b_Low_ind_for_other_Tissue.Value));
            b_high_ind = find(info.dwi.bvals==str2double(app.b_High_ind_for_other_Tissue.Value));
            a = str2double(app.Fit_para_a_for_other_Tissue.Value);
            b = str2double(app.Fit_para_b_for_other_Tissue.Value);
            fprintf("The Index of the low b is: %d\n",b_low_ind);
            fprintf("The Index of the high b is: %d\n",b_high_ind);
            fprintf("The Fit Parameter a is: %0.2f\n",a);
            fprintf("The Fit Parameter b is: %0.2f\n",b);
            im_low = squeeze(data.dwi_raw(:,:,:,b_low_ind));
            im_high = squeeze(data.dwi_raw(:,:,:,b_high_ind));
            fprintf("Starting Fit STIFF Map!!!\n");
            data.result.stiff_map = a.*log(im_low./im_high)+ b;
            app.RunButton_forLover_for_other_Tissue.Enable = 'on';
            fprintf("Fit Successful!!!\n");
        end

        % Button pushed function: CheckSTIFFButton_for_other_Tissue
        function CheckSTIFFButton_for_other_TissuePushed(app, event)
            global data 
            imlook3d(data.result.stiff_map);
        end

        % Button pushed function: SaveResultButton_for_other_Tissue
        function SaveResultButton_for_other_TissuePushed(app, event)
            global data 
            global info
            info.result.stiff_filename = [info.dwi.nii_file_location,'\',info.dwi.file_name,'_stiff_map.nii'];
            nii = info.dwi.nii;
            nii.img = flipud(rot90(data.result.stiff_map,-1));
            nii.hdr.dime.dim(1) = 3;
            nii.hdr.dime.dim(5) = 1;
            nii.hdr.dime.datatype = 16;
            nii.hdr.dime.bitpix = 16;
            nii.fileprefix = [nii.fileprefix,'_stiff'];
            fprintf("Saving STIFF Results\n");
            fprintf("STIFF File Location & name: %s\n",info.result.stiff_filename);
%             nii = make_nii(rot90(data.result.stiff_map,-1), info.dwi.nii_info.dime.pixdim(2:4), [], 16);
            save_untouch_nii(nii, info.result.stiff_filename);
            fprintf("Save STIFF Results Successful!!!\n");
            info.result.roi_filename = [info.dwi.nii_file_location,'\',info.dwi.file_name,'_roi.nii'];
            fprintf("Saving ROI.\n");
            fprintf("ROI File Location & name: %s\n",info.result.roi_filename);
            nii.img = flipud(rot90(data.mre_roi,-1));
            nii.fileprefix = [nii.fileprefix,'_mre_roi'];
%             nii = make_nii(rot90(data.mre_roi,-1), info.mre.nii_info.dime.pixdim(2:4), [], 16);
            save_untouch_nii(nii, info.result.roi_filename);
            fprintf("Save ROI Results Successful!!!\n");
            info.result.reg_filename = [info.dwi.nii_file_location,'\',info.dwi.file_name,'_mre_reg_to_dwi.nii'];
            fprintf("Saving Registration Results\n");
            fprintf("Registration File Location & name: %s\n",info.result.reg_filename);
            nii.img = flipud(rot90(data.mre_reg,-1));
            nii.fileprefix = [nii.fileprefix,'_mre_reg'];
%             nii = make_nii(rot90(data.mre_reg,-1), info.mre.nii_info.dime.pixdim(2:4), [], 16);
            save_untouch_nii(nii, info.result.reg_filename);
            fprintf("Save Registration Results Successful!!!\n");
        end

        % Button pushed function: LoadROIButton
        function LoadROIButtonPushed(app, event)
            global info;
            global data;
            [file,path] = uigetfile('*.nii');
            info.roi.nii_file_location = fullfile(path,file);
            app.FileLocationTextArea_ROI.Value = info.roi.nii_file_location;
            roi_raw = load_untouch_nii([info.roi.nii_file_location]);
            data.mre_roi = double(fliplr(rot90(roi_raw.img)));
            fprintf("Load ROI Successful!!!\n");
            fprintf("ROI File Location & File Name is: %s\n",info.roi.nii_file_location);
        end

        % Button pushed function: LoadCalibButton
        function LoadCalibButtonPushed(app, event)
            global info;
            global data;
            state_ind = 1;
            fit_counter = 0;
            [file,path] = uigetfile('*.xlsx');
            info.calibration.excel_file_location = fullfile(path,file);
            app.FileLocationTextArea_Calib_Excel.Value = info.calibration.excel_file_location;
            [data.calibration.value_data, data.calibration.str_data] = xlsread(info.calibration.excel_file_location);
            data.calibration.stiff_data = data.calibration.value_data(:,1);
            data.calibration.diff_data = data.calibration.value_data(:,2:end);
            for ind_b = 3:size(data.calibration.str_data,2)
                tmp1 = strsplit(data.calibration.str_data{1,ind_b},':');
                info.calibration.bvals(ind_b - 2) = str2num(tmp1{2});
            end
            app.State_box_label.Value{state_ind} ='Starting Calibration Fit alpha, beta, low bval & high bval!!!'; state_ind = state_ind+1;
            num_bvals = length(info.calibration.bvals);
            for ind_low_b = 1:num_bvals-1
                for ind_high_b = ind_low_b+1:num_bvals
                    fit_counter = fit_counter + 1;
                    bval_low(fit_counter) = info.calibration.bvals(ind_low_b);
                    bval_high(fit_counter) = info.calibration.bvals(ind_high_b);
                    im_low = squeeze(data.calibration.diff_data(:,ind_low_b));
                    im_high = squeeze(data.calibration.diff_data(:,ind_high_b));
                    sADC = log(im_low./im_high);
                    sADC(isnan(sADC)) = 0;
                    sADC(isinf(sADC)) = 0;
                    [fitresult, gof] = linearfit_vMRE_other_Tissues(sADC, data.calibration.stiff_data);
                    fit_a(fit_counter) = fitresult.a;
                    fit_b(fit_counter) = fitresult.b;
                    fit_gof(fit_counter) = gof.rsquare;
                    fit_rmse(fit_counter) = gof.rmse;
                end
            end
            %% find the max gof
            best_ind = find(fit_gof == max(fit_gof));
            info.fitresult.b_low = bval_low(best_ind(1));
            info.fitresult.b_high = bval_high(best_ind(1));
            info.fitresult.a = fit_a(best_ind(1));
            info.fitresult.b = fit_b(best_ind(1));
            app.b_Low_ind_for_other_Tissue.Value = num2str(info.fitresult.b_low);
            app.b_High_ind_for_other_Tissue.Value = num2str(info.fitresult.b_high);
            app.Fit_para_a_for_other_Tissue.Value = num2str(info.fitresult.a);
            app.Fit_para_b_for_other_Tissue.Value = num2str(info.fitresult.b);
            app.State_box_label.Value{state_ind} = ['The Index of the low b is: ',num2str(info.fitresult.b_low)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Index of the high b is: ',num2str(info.fitresult.b_high)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Fit Parameter alpha is: ',num2str(info.fitresult.a)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Fit Parameter beta is: ',num2str(info.fitresult.b)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Best (max gof) Fit gof is: ',num2str(max(fit_gof))]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} ='Calibration Fit (max gof) alpha, beta, low bval & high bval Done!!!'; state_ind = state_ind+1;
            fprintf("max gof\n");
            fprintf("The Index of the low b is: %d\n",info.fitresult.b_low);
            fprintf("The Index of the high b is: %d\n",info.fitresult.b_high);
            fprintf("The Fit Parameter alpha is: %0.2f\n",info.fitresult.a);
            fprintf("The Fit Parameter beta is: %0.2f\n",info.fitresult.b);

            best_ind = find(fit_rmse == min(abs(fit_rmse)));
            info.fitresult.b_low = bval_low(best_ind(1));
            info.fitresult.b_high = bval_high(best_ind(1));
            info.fitresult.a = fit_a(best_ind(1));
            info.fitresult.b = fit_b(best_ind(1));
            app.b_Low_ind_for_other_Tissue.Value = num2str(info.fitresult.b_low);
            app.b_High_ind_for_other_Tissue.Value = num2str(info.fitresult.b_high);
            app.Fit_para_a_for_other_Tissue.Value = num2str(info.fitresult.a);
            app.Fit_para_b_for_other_Tissue.Value = num2str(info.fitresult.b);
            app.State_box_label.Value{state_ind} = ['The Index of the low b is: ',num2str(info.fitresult.b_low)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Index of the high b is: ',num2str(info.fitresult.b_high)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Fit Parameter alpha is: ',num2str(info.fitresult.a)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Fit Parameter beta is: ',num2str(info.fitresult.b)]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} = ['The Best (min rmse) Fit rmse is: ',num2str(min(abs(fit_gof)))]; state_ind = state_ind+1;
            app.State_box_label.Value{state_ind} ='Calibration Fit (min rmse) alpha, beta, low bval & high bval Done!!!'; state_ind = state_ind+1;
            fprintf("min rmse");
            fprintf("The Index of the low b is: %d\n",info.fitresult.b_low);
            fprintf("The Index of the high b is: %d\n",info.fitresult.b_high);
            fprintf("The Fit Parameter alpha is: %0.2f\n",info.fitresult.a);
            fprintf("The Fit Parameter beta is: %0.2f\n",info.fitresult.b);
        end

        % Value changed function: MRELoadDicomSwitch
        function MRELoadDicomSwitchValueChanged(app, event)
            value = app.MRELoadDicomSwitch.Value;
            if strcmp(value,'On')
                app.MRELoadNIFTISwitch.Value = 'Off';
            end
        end

        % Value changed function: MRELoadNIFTISwitch
        function MRELoadNIFTISwitchValueChanged(app, event)
            value = app.MRELoadNIFTISwitch.Value;
            if strcmp(value,'On')
                app.MRELoadDicomSwitch.Value = 'Off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileLocationTextAreaLabel = uilabel(app.UIFigure);
            app.FileLocationTextAreaLabel.Text = 'File Location';
            app.FileLocationTextArea_DWI = uitextarea(app.UIFigure);
            app.LoadDWIButton = uibutton(app.UIFigure, 'push');
            app.LoadDWIButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDWIButtonPushed, true);
            app.LoadDWIButton.Text = 'Load DWI';
            app.LoadMREButton = uibutton(app.UIFigure, 'push');
            app.LoadMREButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMREButtonPushed, true);
            app.LoadMREButton.Text = 'Load MRE';
            app.FileLocationTextArea_2Label = uilabel(app.UIFigure);
            app.FileLocationTextArea_2Label.Text = 'File Location';
            app.FileLocationTextArea_MRE = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForLiverTab = uitab(app.TabGroup);
            app.ForLiverTab.Title = 'For Liver';
            app.DWIbValueLabel = uilabel(app.ForLiverTab);
            app.DWIbValueLabel.Text = 'DWI b Value';
            app.bvals_DWI = uitextarea(app.ForLiverTab);
            app.DWIbValueLabel_2 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_2.Text = 'Number of Slices';
            app.Num_Slices = uitextarea(app.ForLiverTab);
            app.DWIbValueLabel_3 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_3.Text = 'Check Image Along b';
            app.Check_Img_B_Slic_ind = uitextarea(app.ForLiverTab);
            app.Check_Img_B_Slic_ind.ValueChangedFcn = createCallbackFcn(app, @Check_Img_B_Slic_indValueChanged, true);
            app.DWIbValueLabel_4 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_4.Text = 'Check Image Along Slice';
            app.Check_Img_Slic_B_ind = uitextarea(app.ForLiverTab);
            app.Check_Img_Slic_B_ind.ValueChangedFcn = createCallbackFcn(app, @Check_Img_Slic_B_indValueChanged, true);
            app.DWIbValueLabel_5 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_5.Text = 'Number of b Values';
            app.Num_bvalues = uitextarea(app.ForLiverTab);
            app.DWIbValueLabel_6 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_6.Text = 'b Low Index';
            app.b_Low_ind = uitextarea(app.ForLiverTab);
            app.DWIbValueLabel_7 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_7.Text = 'b High Index';
            app.b_High_ind = uitextarea(app.ForLiverTab);
            app.DWIbValueLabel_8 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_8.Text = 'Fit Parameter alpha';
            app.Fit_para_a = uitextarea(app.ForLiverTab);
            app.Fit_para_a.Value = {'-9.8'};
            app.DWIbValueLabel_9 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_9.Text = 'Fit Parameter beta';
            app.Fit_para_b = uitextarea(app.ForLiverTab);
            app.Fit_para_b.Value = {'14'};
            app.RunButton_forLover = uibutton(app.ForLiverTab, 'push');
            app.RunButton_forLover.ButtonPushedFcn = createCallbackFcn(app, @RunButton_forLoverPushed, true);
            app.RunButton_forLover.Text = 'Run';
            app.CheckSTIFFButton = uibutton(app.ForLiverTab, 'push');
            app.CheckSTIFFButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSTIFFButtonPushed, true);
            app.CheckSTIFFButton.Text = 'Check STIFF ';
            app.SaveResultButton = uibutton(app.ForLiverTab, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.DWIbValueLabel_19 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_19.Text = 'sADC b Low Index';
            app.sADC_b_Low_ind = uitextarea(app.ForLiverTab);
            app.DWIbValueLabel_20 = uilabel(app.ForLiverTab);
            app.DWIbValueLabel_20.Text = 'sADC b High Index';
            app.sADC_b_High_ind = uitextarea(app.ForLiverTab);
            app.ForOtherTissuesTab = uitab(app.TabGroup);
            app.ForOtherTissuesTab.Title = 'For Other Tissues';
            app.DWIbValueLabel_10 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_10.Text = 'DWI b Value';
            app.bvals_DWI_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.DWIbValueLabel_11 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_11.Text = 'Number of Slices';
            app.Num_Slices_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.DWIbValueLabel_12 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_12.Text = 'Check Image Along b';
            app.Check_Img_B_Slic_ind_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.Check_Img_B_Slic_ind_for_other_Tissue.ValueChangedFcn = createCallbackFcn(app, @Check_Img_B_Slic_ind_for_other_TissueValueChanged, true);
            app.DWIbValueLabel_13 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_13.Text = 'Check Image Along Slice';
            app.Check_Img_Slic_B_ind_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.Check_Img_Slic_B_ind_for_other_Tissue.ValueChangedFcn = createCallbackFcn(app, @Check_Img_Slic_B_ind_for_other_TissueValueChanged, true);
            app.DWIbValueLabel_14 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_14.Text = 'Number of b Values';
            app.Num_bvalues_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.DrawROIButton = uibutton(app.ForOtherTissuesTab, 'push');
            app.DrawROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButtonPushed, true);
            app.DrawROIButton.Text = 'Draw ROI';
            app.CheckROIButton = uibutton(app.ForOtherTissuesTab, 'push');
            app.CheckROIButton.ButtonPushedFcn = createCallbackFcn(app, @CheckROIButtonPushed, true);
            app.CheckROIButton.Text = 'Check ROI';
            app.CheckMREButton = uibutton(app.ForOtherTissuesTab, 'push');
            app.CheckMREButton.ButtonPushedFcn = createCallbackFcn(app, @CheckMREButtonPushed, true);
            app.CheckMREButton.Text = 'Check MRE';
            app.RunButton_forLover_for_other_Tissue = uibutton(app.ForOtherTissuesTab, 'push');
            app.RunButton_forLover_for_other_Tissue.ButtonPushedFcn = createCallbackFcn(app, @RunButton_forLover_for_other_TissuePushed, true);
            app.RunButton_forLover_for_other_Tissue.Text = 'Run';
            app.CheckSTIFFButton_for_other_Tissue = uibutton(app.ForOtherTissuesTab, 'push');
            app.CheckSTIFFButton_for_other_Tissue.ButtonPushedFcn = createCallbackFcn(app, @CheckSTIFFButton_for_other_TissuePushed, true);
            app.CheckSTIFFButton_for_other_Tissue.Text = 'Check STIFF ';
            app.SaveResultButton_for_other_Tissue = uibutton(app.ForOtherTissuesTab, 'push');
            app.SaveResultButton_for_other_Tissue.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButton_for_other_TissuePushed, true);
            app.SaveResultButton_for_other_Tissue.Text = 'Save Result';
            app.DWIbValueLabel_15 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_15.Text = 'b Low Index';
            app.b_Low_ind_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.DWIbValueLabel_16 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_16.Text = 'b High Index';
            app.b_High_ind_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.DWIbValueLabel_17 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_17.Text = 'Fit Parameter a';
            app.Fit_para_a_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.DWIbValueLabel_18 = uilabel(app.ForOtherTissuesTab);
            app.DWIbValueLabel_18.Text = 'Fit Parameter b';
            app.Fit_para_b_for_other_Tissue = uitextarea(app.ForOtherTissuesTab);
            app.RegistrationSwitchLabel = uilabel(app.ForOtherTissuesTab);
            app.RegistrationSwitchLabel.Text = 'Registration';
            app.RegistrationSwitch = uiswitch(app.ForOtherTissuesTab, 'slider');
            app.RegistrationSwitch.Value = 'On';
            app.LoadROIButton = uibutton(app.ForOtherTissuesTab, 'push');
            app.LoadROIButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIButtonPushed, true);
            app.LoadROIButton.Text = 'Load ROI';
            app.FileLocationTextAreaLabel_2 = uilabel(app.ForOtherTissuesTab);
            app.FileLocationTextAreaLabel_2.Text = 'File Location';
            app.FileLocationTextArea_ROI = uitextarea(app.ForOtherTissuesTab);
            app.ROItoROICalibTab = uitab(app.TabGroup);
            app.ROItoROICalibTab.Title = 'ROI to ROI Calib';
            app.FileLocationTextAreaLabel_3 = uilabel(app.ROItoROICalibTab);
            app.FileLocationTextAreaLabel_3.Text = 'File Location';
            app.FileLocationTextArea_Calib_Excel = uitextarea(app.ROItoROICalibTab);
            app.FileLocationTextArea_Calib_Excel.Value = {'ROI to ROI Calibration data Excel file'};
            app.LoadCalibButton = uibutton(app.ROItoROICalibTab, 'push');
            app.LoadCalibButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibButtonPushed, true);
            app.LoadCalibButton.Text = 'Load Calib';
            app.StateLabel = uilabel(app.ROItoROICalibTab);
            app.StateLabel.Text = 'State:';
            app.State_box_label = uitextarea(app.ROItoROICalibTab);
            app.VirtualMRELabel = uilabel(app.UIFigure);
            app.VirtualMRELabel.Text = 'Virtual MRE';
            app.LoadDWINIIButton = uibutton(app.UIFigure, 'push');
            app.LoadDWINIIButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDWINIIButtonPushed, true);
            app.LoadDWINIIButton.Text = 'Load DWI NII';
            app.MRELoadDicomSwitchLabel = uilabel(app.UIFigure);
            app.MRELoadDicomSwitchLabel.Text = 'MRE Load Dicom';
            app.MRELoadDicomSwitch = uiswitch(app.UIFigure, 'slider');
            app.MRELoadDicomSwitch.ValueChangedFcn = createCallbackFcn(app, @MRELoadDicomSwitchValueChanged, true);
            app.MRELoadNIFTISwitchLabel = uilabel(app.UIFigure);
            app.MRELoadNIFTISwitchLabel.Text = 'MRE Load NIFTI';
            app.MRELoadNIFTISwitch = uiswitch(app.UIFigure, 'slider');
            app.MRELoadNIFTISwitch.ValueChangedFcn = createCallbackFcn(app, @MRELoadNIFTISwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vMRE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end